classdef set_proc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UpdateValueButton        matlab.ui.control.Button
        FieldNameDropDown        matlab.ui.control.DropDown
        FieldNameDropDownLabel   matlab.ui.control.Label
        ValueEditField           matlab.ui.control.EditField
        ValueEditFieldLabel      matlab.ui.control.Label
        ReloadDefaultsButton     matlab.ui.control.Button
        ExitandsetValuesButton   matlab.ui.control.Button
        ExitwithoutvaluesButton  matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        ProcessingOptionsLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        proc_pars          ;
        proc_pars_table    ;
        parent_app      ;
        default_options ;
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_ap, struct_1, struct_2)
            app.proc_pars       = struct_1   ;
            app.default_options = struct_2   ;
            app.parent_app = parent_ap;

            app.FieldNameDropDown.Items = fieldnames(app.proc_pars)';


            app.proc_pars_table = create_table_from_structure(app.proc_pars);
            app.UITable.Data = app.proc_pars_table;
            app.UITable.ColumnName = app.proc_pars_table.Properties.VariableNames;


            app.ValueEditField.Value = num2str(getfield(app.proc_pars,app.FieldNameDropDown.Value));


        end

        % Button pushed function: ReloadDefaultsButton
        function ReloadDefaultsButtonPushed(app, event)
                       
           [app.proc_pars,ok_] = load_structure_from_file([pwd,app.default_options.proc_options_fname],....
           app.default_options.proc_expected_fields);

           if ok_~= 1 ; msgbox('Warning:: structure fields dont match defaults') ;end      
           
           % now reset the display boxes and table
           app.proc_pars_table  = create_table_from_structure(app.proc_pars);
           app.UITable.Data = app.proc_pars_table;
           

        end

        % Button pushed function: ExitwithoutvaluesButton
        function ExitwithoutvaluesButtonPushed(app, event)
              delete(app)
        end

        % Button pushed function: ExitandsetValuesButton
        function ExitandsetValuesButtonPushed(app, event)
            set_procsettings(app.parent_app ,app.proc_pars)
            delete(app)           
        end

        % Value changed function: FieldNameDropDown
        function FieldNameDropDownValueChanged(app, event)
            value = app.FieldNameDropDown.Value;
            app.ValueEditField.Value = num2str(getfield(app.proc_pars,app.FieldNameDropDown.Value));
        end

        % Button pushed function: UpdateValueButton
        function UpdateValueButtonPushed(app, event)
            
            if ~isempty(str2num(app.ValueEditField.Value))
            pass_val =  str2num(app.ValueEditField.Value); 
            disp('here1')
            else
            pass_val = app.ValueEditField.Value;
            disp('here2')
            end


            [messge_ , check_ok] = Check_Proc_settings(app.FieldNameDropDown.Value,pass_val);
                        
            if check_ok ==1

            %app.operator_settings = setfield(app.operator_settings,num2str(app.FieldNameDropDown.Value), pass_val);
            %app.operator_settings = setfield(app.operator_settings,num2str(app.FieldNameDropDown.Value), app.ValueEditField.Value);
            
            app.proc_pars           = setfield(app.proc_pars,num2str(app.FieldNameDropDown.Value), pass_val);
            app.proc_pars_table     = create_table_from_structure(app.proc_pars);
            app.UITable.Data        = app.proc_pars_table;
            app.UITable.ColumnName  = app.proc_pars_table.Properties.VariableNames;
            
            else
            msgbox(messge_)    
            end %if check_ok ==1

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProcessingOptionsLabel = uilabel(app.UIFigure);
            app.ProcessingOptionsLabel.Text = 'Processing Options';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ExitwithoutvaluesButton = uibutton(app.UIFigure, 'push');
            app.ExitwithoutvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @ExitwithoutvaluesButtonPushed, true);
            app.ExitwithoutvaluesButton.Text = 'Exit without values';
            app.ExitandsetValuesButton = uibutton(app.UIFigure, 'push');
            app.ExitandsetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ExitandsetValuesButtonPushed, true);
            app.ExitandsetValuesButton.Text = {'Exit and set'; 'Values'};
            app.ReloadDefaultsButton = uibutton(app.UIFigure, 'push');
            app.ReloadDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadDefaultsButtonPushed, true);
            app.ReloadDefaultsButton.Text = 'Reload Defaults';
            app.ValueEditFieldLabel = uilabel(app.UIFigure);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.UIFigure, 'text');
            app.FieldNameDropDownLabel = uilabel(app.UIFigure);
            app.FieldNameDropDownLabel.Text = 'Field Name';
            app.FieldNameDropDown = uidropdown(app.UIFigure);
            app.FieldNameDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldNameDropDownValueChanged, true);
            app.UpdateValueButton = uibutton(app.UIFigure, 'push');
            app.UpdateValueButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateValueButtonPushed, true);
            app.UpdateValueButton.Text = 'Update Value';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = set_proc(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end